classdef ElevatorInsideUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ElevatorInDoorUI     matlab.ui.Figure
        Door                 matlab.ui.control.Image
        Label                matlab.ui.control.Label
        Panel                matlab.ui.container.Panel
        OpenButton           matlab.ui.control.Button
        CloseButton          matlab.ui.control.Button
        Button_2             matlab.ui.control.Button
        Button_3             matlab.ui.control.Button
        Button_B1            matlab.ui.control.Button
        Button_1             matlab.ui.control.Button
        CurrPosition         matlab.ui.control.NumericEditField
        Direct               matlab.ui.control.EditField
        speedEditFieldLabel  matlab.ui.control.Label
        Speed                matlab.ui.control.NumericEditField
        heightLabel          matlab.ui.control.Label
        Height               matlab.ui.control.NumericEditField
    end

    properties (Access = public)
        Process % Controller
        Elevator
        ID
    end
    
    methods (Access = public)
        function openDoor(app)
            if(app.Elevator.speed~=0)
                app.Elevator.speed=0;
                app.Speed.Value=0;
                pause(1);
            end
            app.Door.ImageSource = 'open_e.png';
        end
        
        function update(app)
            % position
            pos = app.Elevator.position;
            if pos>-1
                pos = pos+1;
            end
            app.CurrPosition.Value = pos;
            
            % speed, height
            app.Speed.Value = app.Elevator.speed;
            app.Height.Value = app.Elevator.height;
            
            % door state
            state=app.Elevator.state;
            switch state
                case 0 % close
                    app.closeDoor();
                case 1 % close
                    app.openDoor();
            end
        end
    
        function closeDoor(app)
            app.Door.ImageSource= 'close_e.png';
            app.Elevator.state = 0;
        end
        
        function enableButton(app, floor)
            switch floor
                case -1
                    app.Button_B1.Enable='on';
                case 0
                    app.Button_1.Enable='on';
                case 1
                    app.Button_2.Enable='on';
                case 2
                    app.Button_3.Enable='on';
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Controller, elevator, elevatorID)
            app.Process = Controller;
            app.Elevator = elevator;
            app.ID = elevatorID;
            if(app.ID==1)
                app.Label.Text="LeftElevator id="+app.ID;
                app.Button_B1.Text=" ";
                app.Button_B1.Enable='off';
                % place the UI
                app.ElevatorInDoorUI.Position=[80,300,410,336];
            else
                app.Label.Text="RightElevator id="+app.ID;
                app.ElevatorInDoorUI.Position=[510,300,410,336];
            end
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            app.OpenButton.Enable='off';
            app.Process.pushOpenDoorAtFloor(app.ID, app.Elevator.position);
            app.OpenButton.Enable='on';
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.Process.closeDoorAtFloor(app.ID, app.Elevator.position);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.Button_2.Enable='off';
            if(app.Process.goto(app.ID, 1))==1
                app.Button_2.Enable='on';
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.Button_3.Enable='off';
            if(app.Process.goto(app.ID, 2)==1)
                app.Button_3.Enable='on';
            end
        end

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            app.Button_1.Enable='off';
            if (app.Process.goto(app.ID, 0))==1
                app.Button_1.Enable='on';
            end
            
        end

        % Button pushed function: Button_B1
        function Button_B1Pushed(app, event)
            app.Button_B1.Enable='off';
            if (app.Process.goto(app.ID, -1))==1
                app.Button_B1.Enable='on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ElevatorInDoorUI = uifigure('Visible', 'off');
            app.ElevatorInDoorUI.Name = 'Elevator Inside UI Figure';
            app.Door = uiimage(app.ElevatorInDoorUI);
            app.Door.ImageSource = 'close_e.png';
            app.Label = uilabel(app.ElevatorInDoorUI);
            app.Label.Text = 'LeftElevator';
            app.Panel = uipanel(app.ElevatorInDoorUI);
            app.OpenButton = uibutton(app.Panel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = '<|>';
            app.CloseButton = uibutton(app.Panel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = '>|<';
            app.Button_2 = uibutton(app.Panel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.Panel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_B1 = uibutton(app.Panel, 'push');
            app.Button_B1.ButtonPushedFcn = createCallbackFcn(app, @Button_B1Pushed, true);
            app.Button_B1.Text = '-1';
            app.Button_1 = uibutton(app.Panel, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '1';
            app.CurrPosition = uieditfield(app.Panel, 'numeric');
            app.CurrPosition.Value = 1;
            app.Direct = uieditfield(app.Panel, 'text');
            app.speedEditFieldLabel = uilabel(app.ElevatorInDoorUI);
            app.speedEditFieldLabel.Text = 'speed';
            app.Speed = uieditfield(app.ElevatorInDoorUI, 'numeric');
            app.heightLabel = uilabel(app.ElevatorInDoorUI);
            app.heightLabel.Text = 'height';
            app.Height = uieditfield(app.ElevatorInDoorUI, 'numeric');
            % Show the figure after all components are created
            app.ElevatorInDoorUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ElevatorInsideUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ElevatorInDoorUI)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ElevatorInDoorUI)
        end
    end
end