classdef ElevatorOutsideUI_F1_F2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ElevatorOutDoorUI  matlab.ui.Figure
        Panel              matlab.ui.container.Panel
        LeftDoor           matlab.ui.control.Image
        UpButton           matlab.ui.control.Button
        DownButton         matlab.ui.control.Button
        LeftPosition       matlab.ui.control.NumericEditField
        LeftLamp           matlab.ui.control.Lamp
        RightDoor          matlab.ui.control.Image
        RightLamp          matlab.ui.control.Lamp
        RightPosition      matlab.ui.control.NumericEditField
        LeftDirect         matlab.ui.control.EditField
        RightDirect        matlab.ui.control.EditField
    end

    
    properties (Access = public)
        Process
        Floor% F1 or F2
    end
    
    methods (Access = public)
        % close the door at certain floor
        function closeDoor(app, id)
            switch id
                case 1
                    app.LeftDoor.ImageSource = 'close_e.png';
                case 2
                    app.RightDoor.ImageSource = 'close_e.png';
            end
            if strcmp(app.UpButton.Enable, 'off')
                app.UpButton.Enable='on';
            end
            if strcmp(app.DownButton.Enable, 'off')
                app.DownButton.Enable='on';
            end
        end
        
        % close the door at certain floor
        function openDoor(app, id)
            switch id
                case 1
                    app.LeftDoor.ImageSource = 'open_e.png';
                case 2
                    app.RightDoor.ImageSource = 'open_e.png';
            end
        end
        
        % switch on/ off the lamp
        function switchLamp(app, id, state)
            red = [0.64,0.08,0.18];
            green = [0.39,0.83,0.07];
            if strcmp(state, 'on')
                color = green;
            elseif strcmp(state, 'off')
                color = red;
            end
            switch id
                case 1
                    app.LeftLamp.Color = color;
                case 2
                    app.RightLamp.Color = color;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Label)
            % initial floor number
            app.Floor = Label;
            app.Panel.Title=Label+"F";
            if strcmp(app.Floor, '2')
                app.ElevatorOutDoorUI.Position = [950, 350, 380, 200];
            end
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            app.UpButton.Enable='off';
            if strcmp(app.Floor, '1')
                if(app.Process.goto(0, 0))==1
                    app.UpButton.Enable='on';
                end
            else
                if(app.Process.goto(0, 1))==1
                    app.UpButton.Enable='on';
                end
            end
            %app.UpButton.Enable='on';
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            app.DownButton.Enable='off';
            if strcmp(app.Floor, '1')
                if(app.Process.goto(0, 0))==1
                    app.DownButton.Enable='on';
                end
            else
                if(app.Process.goto(0, 1))==1
                    app.DownButton.Enable='on';
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ElevatorOutDoorUI = uifigure('Visible', 'off');
            app.ElevatorOutDoorUI.Name = 'Elevator Outside UI Figure';
            app.Panel = uipanel(app.ElevatorOutDoorUI);
            app.Panel.Title = '1 F';
            app.LeftDoor = uiimage(app.Panel);
            app.LeftDoor.ImageSource = 'close_e.png';
            app.UpButton = uibutton(app.Panel, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = '↑';
            app.DownButton = uibutton(app.Panel, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = '↓';
            app.LeftPosition = uieditfield(app.Panel, 'numeric');
            app.LeftPosition.Value = 1;
            app.LeftLamp = uilamp(app.Panel);
            app.RightDoor = uiimage(app.Panel);
            app.RightDoor.ImageSource = 'close_e.png';
            app.RightLamp = uilamp(app.Panel);
            app.RightPosition = uieditfield(app.Panel, 'numeric');
            app.RightPosition.Value = 1;
            app.LeftDirect = uieditfield(app.Panel, 'text');
            app.RightDirect = uieditfield(app.Panel, 'text');
            % Show the figure after all components are created
            app.ElevatorOutDoorUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ElevatorOutsideUI_F1_F2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ElevatorOutDoorUI)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ElevatorOutDoorUI)
        end
    end
end