classdef ElevatorOutsideUI_F3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Panel          matlab.ui.container.Panel
        LeftDoor       matlab.ui.control.Image
        DownButton     matlab.ui.control.Button
        LeftPosition   matlab.ui.control.NumericEditField
        LeftLamp       matlab.ui.control.Lamp
        RightDoor      matlab.ui.control.Image
        RightLamp      matlab.ui.control.Lamp
        RightPosition  matlab.ui.control.NumericEditField
        LeftDirect     matlab.ui.control.EditField
        RightDirect    matlab.ui.control.EditField
    end

properties (Access = public)
        Process
    end
    
    methods (Access = public)
        % close the door at certain floor
        function closeDoor(app, id)
            switch id
                case 1
                    app.LeftDoor.ImageSource = 'close_e.png';
                case 2
                    app.RightDoor.ImageSource = 'close_e.png';
            end
            if strcmp(app.DownButton.Enable, 'off')
                app.DownButton.Enable='on';
            end
        end
        
        % close the door at certain floor
        function openDoor(app, id)
            switch id
                case 1
                    app.LeftDoor.ImageSource = 'open_e.png';
                case 2
                    app.RightDoor.ImageSource = 'open_e.png';
            end
        end
        
        % switch on/ off the lamp
        function switchLamp(app, id, state)
            red = [0.64,0.08,0.18];
            green = [0.39,0.83,0.07];
            if strcmp(state, 'on')
                color = green;
            elseif strcmp(state, 'off')
                color = red;
            end
            switch id
                case 1
                    app.LeftLamp.Color = color;
                case 2
                    app.RightLamp.Color = color;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            app.DownButton.Enable='off';
            if(app.Process.goto(0, 2)==1)
                app.DownButton.Enable='on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '3 F';
            app.LeftDoor = uiimage(app.Panel);
            app.LeftDoor.ImageSource = 'close_e.png';
            app.DownButton = uibutton(app.Panel, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = '↓';
            app.LeftPosition = uieditfield(app.Panel, 'numeric');
            app.LeftPosition.Value = 1;
            app.LeftLamp = uilamp(app.Panel);
            app.RightDoor = uiimage(app.Panel);
            app.RightDoor.ImageSource = 'close_e.png';
            app.RightLamp = uilamp(app.Panel);
            app.RightPosition = uieditfield(app.Panel, 'numeric');
            app.RightPosition.Value = 1;
            app.LeftDirect = uieditfield(app.Panel, 'text');
            app.RightDirect = uieditfield(app.Panel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ElevatorOutsideUI_F3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end